classdef RX150 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        ConnectionLampLabel  matlab.ui.control.Label
        ConnectionLamp       matlab.ui.control.Lamp
        Servo1KnobLabel      matlab.ui.control.Label
        Servo1Knob           matlab.ui.control.Knob
        Servo2KnobLabel      matlab.ui.control.Label
        Servo2Knob           matlab.ui.control.Knob
        Servo3KnobLabel      matlab.ui.control.Label
        Servo3Knob           matlab.ui.control.Knob
        CenterPanel          matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        StatusTextAreaLabel  matlab.ui.control.Label
        StatusTextArea       matlab.ui.control.TextArea
        MoveButton           matlab.ui.control.Button
        XEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        YEditField           matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        ZEditField           matlab.ui.control.NumericEditField
        RightPanel           matlab.ui.container.Panel
        Servo4KnobLabel      matlab.ui.control.Label
        Servo4Knob           matlab.ui.control.Knob
        Servo5KnobLabel      matlab.ui.control.Label
        Servo5Knob           matlab.ui.control.Knob
        Servo6KnobLabel      matlab.ui.control.Label
        Servo6Knob           matlab.ui.control.Knob
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Member Variables
    properties (Access = private)
        didLoadLib;
        didOpenPort;
        didSetBaudRate;
        didLoadModel;
        lib_name = 'dxl_x64_c';     % Dynamixel library name
        urdf_path = 'C:\Users\smith\Documents\GitHub\interbotix_ros_arms\interbotix_descriptions\urdf\rx150.urdf';                  % Path to .urdf model
        
        model;                      % Simualation model
        home_pose;                  % Starting pose
        target_pose;                % Target pose IK will attempt to reach from starting pose
        messages;                   % Description
        end_pose;                   % Final pose is the solution reached by IK
        port;                       % Port number
        dxl_comm_result;            % Communication result
        dxl_getdata_result;         % GetParam result
        dxl_goal_position;          % Goal position
        dxl_error;                  % Dynamixel error
        dxl_led_value;              % Dynamixel LED value for write
    end
    
    % Constants
    properties (Access = private, Constant)
        ADDR_TORQUE_ENABLE = 64;                    % Dynamixel Control table address which controls torque (enable allows write to table)
        ADDR_LED_RED = 65;                          % Dynamixel Control table address enables/disables LED
        ADDR_GOAL_POSITION = 116;                   % Dynamixel Control table address sets goal position
        ADDR_PRESENT_POSITION = 132;                % Dynamixel Control table address that provides present position
        ADDR_RETURN_DELAY_TIME = 8;                 % Dynamixel Control table address that controls return delay time
        
        LEN_PRO_LED_RED = 1;                        % Length of message in bytes when addressing LED control table location
        LEN_PRO_GOAL_POSITION = 4;                  % Length of message in bytes when addressing goal position control table location
        LEN_PRO_PRESENT_POSITION = 4;               % Length of message in bytes when addressing present position table location
        
        DXL_IDS = [0 1 2 3 4 5];                    % ID numbers of dynamixel servos
        
        PROTOCOL_VERSION = 2.0;                     % Protocol version that commands use to communicate with Dynamixel servos
        DXL_MINIMUM_POSITION_VALUE = 0;             % Minimum goal position value that servos will accept
        DXL_MAXIMUM_POSITION_VALUE = 4095;          % Maximum goal position value that servos accept
        DXL_MOVING_STATUS_THRESHOLD = 20;           % Dynamixel moving status threshold
        
        BAUD_RATE = 1000000;                        % Default data rate used during communication with servos (in bps)
        DEVICE_NAME = 'COM3';                       % Port name used during communication with Dynamixel servos
        COMM_SUCCESS = 0;                           % Communication Success result value
        COMM_TX_FAIL = -1001;                       % Communication Tx failed result value
        COMM_PORT_BUSY = -1000;                     % Communication port was busy
        
        ESC_CHARACTER = 'e';                        % Key for escaping loop
        
        TORQUE_ENABLE = 1;                          % Value for enabling the torque
        TORQUE_DISABLE = 0;                         % Value for disabling the torque
        RETURN_DELAY_TIME = 10;                     % Amount of time(2us) Dynamixel waits before replying
        
        BASE_LINK = 'base_link';                    % Base link
        END_EFFECTOR = 'wrist_link';                % End effector
        
        PI = 3.1415926535897932384626433832795;     % pi in radians
    end
    
    % Helper methods
    methods (Access = private)
        % Enables torque on a given servo
        function results = EnableTorque(app, id)
            if app.didOpenPort
                write1ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_TORQUE_ENABLE, app.TORQUE_ENABLE);
        
                app.dxl_comm_result = getLastTxRxResult(app.port, app.PROTOCOL_VERSION);
                app.dxl_error = getLastRxPacketError(app.port, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= 0
                    results = false;
                    fprintf('%s %d\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result),app.dxl_comm_result);
                elseif app.dxl_error ~= 0
                    results = false;
                    fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
                else
                    results = true;
                    fprintf('Dynamixel %d has been successfully connected \n', id);
                end
                
                %results = app.dxl_comm_result & app.dxl_error;
            else
                results = false;
            end
        end
        
        % Disables torque on a given servo
        function results = DisableTorque(app, id)
            if app.didOpenPort
                write1ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_TORQUE_ENABLE, app.TORQUE_DISABLE);
        
                app.dxl_comm_result = getLastTxRxResult(app.port, app.PROTOCOL_VERSION);
                app.dxl_error = getLastRxPacketError(app.port, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= 0
                    results = false;
                    fprintf('%s %d\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result),app.dxl_comm_result);
                elseif app.dxl_error ~= 0
                    results = false;
                    fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
                else
                    results = true;
                    fprintf('Dynamixel %d has been disconnected \n', id);
                end
                
                %results = app.dxl_comm_result & app.dxl_error;
            else
                results = false;
            end   
            
        end
        
        % Sets the goal position (angle) of a servo in the robotic arm
        function results = SetGoalPosition(app, id, goal)
            if app.didOpenPort
                if  (app.DXL_MINIMUM_POSITION_VALUE < goal) && (goal < app.DXL_MAXIMUM_POSITION_VALUE)
                    write4ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_GOAL_POSITION, uint16(goal));
                    dxl_present_position = read4ByteTxRx(app.port, app.PROTOCOL_VERSION, id, app.ADDR_PRESENT_POSITION);
                    if getLastTxRxResult(app.port, app.PROTOCOL_VERSION) ~= 0
                        %printTxRxResult(app.PROTOCOL_VERSION, getLastTxRxResult(app.port, app.PROTOCOL_VERSION));
                        printTxRxResult(uint(2), uint(getLastTxRxResult(0, 2)));
                    elseif getLastRxPacketError(app.port, app.PROTOCOL_VERSION) ~= 0
                        %printRxPacketError(protocol, getLastRxPacketError(port, protocol));
                    end
                end
            end
        end
        
        % Sets the pose (group of angles) of the robotic arm in reverse order
        function results = SetPose(app, pose)
            if app.didOpenPort
                len = length(pose);
                goalpos = Deg2GoalPos(app, pose);
                disp(goalpos)
                for i = len:-1:1
                    SetGoalPosition(app,i-1,goalpos(i));
                end
            end
        end
        
        % Creates a configuration of joints from the default home configuration
        function results = SetConfiguration(app, arr)
            if app.didLoadModel
                config = app.model.homeConfiguration;
        
                for i = [1 2 3 4 5 6]
                    theta = deg2rad(180 - arr(i));
                    config(i).JointPosition = theta;
                end
                
                results = config;
            else
                results = struct('JointName',{},'JointPosition',{});
            end
        end
        
        % Iteratively solves the problem of which joint angles will produce a given pose 
        function results = IKSolver(app, config)
            if app.didLoadModel
                transform = getTransform(app.model,config,app.END_EFFECTOR,app.BASE_LINK);
    
                weights = [0.01 0.01 0.01 0.1 0.1 0.1];
                initialguess = app.model.homeConfiguration;
            
                ik = inverseKinematics;
                ik.RigidBodyTree = app.model;
                
                [configSoln,solnInfo] = ik(app.END_EFFECTOR,transform,weights,initialguess);
                results = configSoln;
            else
                results = struct('JointName',{},'JointPosition',{});
            end
        end
        
        % Converts a solution from the simulation into joint positions (angles) in the frame of the robotic arm
        function results = ConvertSolnToPose(app, config)
            results = zeros(1,6);
                
            results(1) = rad2deg(app.PI - config(1).JointPosition);
            results(2) = rad2deg(app.PI - config(2).JointPosition);
            results(3) = rad2deg(app.PI - config(3).JointPosition);
            results(4) = rad2deg(app.PI - config(4).JointPosition);
            results(5) = rad2deg(app.PI - config(5).JointPosition);
            results(6) = rad2deg(app.PI - config(6).JointPosition);
        end
        
        % Converts a given angle in degrees to a goal position
        function results = Deg2GoalPos(app, deg)
            results = deg * (4096/360);
        end
        
        % Loads Dynamixel libraries
        function results = LoadDynamixelLibs(app)
            clc;
            clear all;
            
            % Get library name
            if strcmp(computer, 'PCWIN')
              app.lib_name = 'dxl_x86_c';
            elseif strcmp(computer, 'PCWIN64')
              app.lib_name = 'dxl_x64_c';
            elseif strcmp(computer, 'GLNX86')
              app.lib_name = 'libdxl_x86_c';
            elseif strcmp(computer, 'GLNXA64')
              app.lib_name = 'libdxl_x64_c';
            elseif strcmp(computer, 'MACI64')
              app.lib_name = 'libdxl_mac_c';
            end
            
            % Load Libraries
            if ~libisloaded(app.lib_name)
                addpath(fullfile("C:/","Users","smith","Documents","GitHub","DynamixelSDK","matlab"));
                [notfound, warnings] = loadlibrary(app.lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_bulk_read.h', 'addheader', 'group_bulk_write.h');
                
                %if ~isempty(notfound{1}) & ~isempty(notfound{2}) & ~isempty(notfound{3}) & ~isempty(notfound{4})
                if numel(notfound) == 0
                   results = true;
                else
                    results = false;
                end
            else
                results = true;
            end
        end
        
        % Load 3D model from .urdf file
        function results = LoadModel(app)
            if ismac
                app.urdf_path = "/Users/smith/Documents/GitHub/interbotix_ros_arms/interbotix_descriptions/urdf/rx150.urdf";
                app.model = importrobot(app.urdf_path);
                results = true;
            elseif isunix
                app.urdf_path = "/Users/smith/Documents/GitHub/interbotix_ros_arms/interbotix_descriptions/urdf/rx150.urdf";
                app.model = importrobot(app.urdf_path);
                results = true;
            elseif ispc
                app.urdf_path = "C:\Users\smith\Documents\GitHub\interbotix_ros_arms\interbotix_descriptions\urdf\rx150.urdf";
                app.model = importrobot(app.urdf_path);
                results = true;
            else
                disp('Platform not supported')
                results = false;
            end
        end
        
        % Unload Library
        function results = UnloadDynamixelLibs(app)
            if app.didLoadLib
                app.didLoadLib = false;
                unloadlibrary(app.lib_name);
            end
        end
        
        % Enable torque on all servos
        function results = EnableAllTorques(app)
            for i = [1 2 3 4 5 6]
                EnableTorque(app, app.DXL_IDS(i));
            end
        end
        
        % Disable torque on all servos
        function results = DisableAllTorques(app)
            for i = [1 2 3 4 5 6]
                 DisableTorque(app, app.DXL_IDS(i));
            end
        end
        
        % Initializes member variables and opens communication with
        % dynamixel servos
        function results = Init(app)
            % Initialize PortHandler Structs
            % Set the port number
            app.port = portHandler(app.DEVICE_NAME);
            
            % Initialize PacketHandler Structs
            packetHandler();
            
            app.dxl_comm_result = app.COMM_TX_FAIL;                                                         % Communication result
            app.dxl_goal_position = [app.DXL_MINIMUM_POSITION_VALUE app.DXL_MAXIMUM_POSITION_VALUE];        % Goal position 
            app.dxl_error = 0;                                                                              % Dynamixel error
            app.dxl_led_value = [0 255];                                                                    % Dynamixel LED value for write
            
            app.didOpenPort = OpenCommPort(app);
            
            app.didSetBaudRate = SetPortBaudRate(app);
            
            % Enable all servos
            EnableAllTorques(app);
            
            % Set the pose of the robotic arm to the default home position
            app.home_pose = [180 180 180 180 180 180];
            SetPose(app,app.home_pose);
            
            % Set the initial target pose
            app.target_pose = [180 180 180 180 180 180];
            
             % Set the final pose
            app.end_pose = [180 180 180 180 180 180];
            
            % Load .urdf model into memory
            app.didLoadModel = LoadModel(app);
            
            results = app.didOpenPort & app.didSetBaudRate & app.didLoadModel;
        end
        
        % Open port
        function results = OpenCommPort(app)
            if app.didLoadLib
                if (openPort(app.port))
                    app.ConnectionLamp.Color = [0.00 1.00 0.00];
                    fprintf('Succeeded to open port %d!\n',app.port);
                    results = true;
                else
                    UnloadDynamixelLibs(app);
                    app.ConnectionLamp.Color = [1.00 0.00 0.00];
                    fprintf('Failed to open the port!\n');
                    results = false;
                end
            else
                results = false;
            end
        end
        
        % Set port baudrate
        function results = SetPortBaudRate(app)
            if app.didOpenPort
                if (setBaudRate(app.port, app.BAUD_RATE))
                    fprintf('Succeeded to change the baudrate!\n');
                    results = true;
                else
                    UnloadDynamixelLibs(app);
                    fprintf('Failed to change the baudrate!\n');
                    results = false;
                end
            else
                results = false;
            end
        end
        
        % Close port
        function results = CloseCommPort(app)
            if app.didOpenPort
                closePort(app.port);
                results = false;
                disp("Closed port");
            else
                results = app.didOpenPort;
            end
        end
        
        function results = DisplayModel(app, pose)
            config = SetConfiguration(app,pose);
            %app.UIFigure.AutoResizeChildren = 'off';
            %ax = subplot(1,3,1,'Parent',app.UIFigure);
            %app.UIAxes = uiaxes(app.CenterPanel);
            show(app.model,config);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.didLoadLib = LoadDynamixelLibs(app);
            Init(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            DisableAllTorques(app);
            app.didOpenPort = CloseCommPort(app);
            UnloadDynamixelLibs(app);
            delete(app);
        end

        % Value changed function: Servo1Knob
        function Servo1KnobValueChanged(app, event)
            value = app.Servo1Knob.Value; 
            if ((value >= 0) && (value <= 360))
                app.target_pose(1) = value;
                app.messages{end+1} = strcat('Servo #1: ',num2str(value));
                app.StatusTextArea.Value = app.messages;
                DisplayModel(app, app.target_pose);
            end
        end

        % Value changed function: Servo2Knob
        function Servo2KnobValueChanged(app, event)
            value = app.Servo2Knob.Value; 
            if ((value >= 0) && (value <= 180))
                app.target_pose(2) = value;
                app.messages{end+1} = strcat('Servo #2: ',num2str(value));
                app.StatusTextArea.Value = app.messages;
                DisplayModel(app, app.target_pose);
            end
        end

        % Value changed function: Servo3Knob
        function Servo3KnobValueChanged(app, event)
            value = app.Servo3Knob.Value;
            if ((value >= 0) && (value <= 180))
                app.target_pose(3) = value;
                app.messages{end+1} = strcat('Servo #3: ',num2str(value));
                app.StatusTextArea.Value = app.messages;
                DisplayModel(app, app.target_pose);
            end
        end

        % Value changed function: Servo4Knob
        function Servo4KnobValueChanged(app, event)
            value = app.Servo4Knob.Value;
            if ((value >= 0) && (value <= 180))
                app.target_pose(4) = value;
                app.messages{end+1} = strcat('Servo #4: ',num2str(value));
                app.StatusTextArea.Value = app.messages;
                DisplayModel(app, app.target_pose);
            end
        end

        % Value changed function: Servo5Knob
        function Servo5KnobValueChanged(app, event)
            value = app.Servo5Knob.Value;
            if ((value >= 0) && (value <= 360))
                app.target_pose(5) = value;
                app.messages{end+1} = strcat('Servo #5: ',num2str(value));
                app.StatusTextArea.Value = app.messages;
                DisplayModel(app, app.target_pose);
            end
        end

        % Value changed function: Servo6Knob
        function Servo6KnobValueChanged(app, event)
            value = app.Servo6Knob.Value;
            if ((value >= 0) && (value <= 180))
                app.target_pose(6) = value;
                app.messages{end+1} = strcat('Servo #6: ',num2str(value));
                app.StatusTextArea.Value = app.messages;
                DisplayModel(app, app.target_pose);
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            finalConfiguration = SetConfiguration(app, app.target_pose);

            soln = IKSolver(app, finalConfiguration);
            
            % Convert the inverse kinematic solution into a set of angles suitable for
            % the robotic arm
            app.end_pose =  ConfigSoln2Pose(app,soln);
            
            
            %subplot(1,3,2);show(robot,soln);
            SetPose(app, app.end_pose);
            app.home_pose = app.end_pose;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {586, 586, 586};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {586, 586};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 224};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectionLampLabel = uilabel(app.LeftPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.LeftPanel);
            app.ConnectionLamp.Tooltip = {'Connection State (OPEN/CLOSED)'};
            app.Servo1KnobLabel = uilabel(app.LeftPanel);
            app.Servo1KnobLabel.Text = 'Servo #1';
            app.Servo1Knob = uiknob(app.LeftPanel, 'continuous');
            app.Servo1Knob.Limits = [0 360];
            app.Servo1Knob.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Servo1Knob.ValueChangedFcn = createCallbackFcn(app, @Servo1KnobValueChanged, true);
            app.Servo1Knob.Tooltip = {'Waist'};
            app.Servo2KnobLabel = uilabel(app.LeftPanel);
            app.Servo2KnobLabel.Text = 'Servo #2';
            app.Servo2Knob = uiknob(app.LeftPanel, 'continuous');
            app.Servo2Knob.Limits = [0 180];
            app.Servo2Knob.MajorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180];
            app.Servo2Knob.ValueChangedFcn = createCallbackFcn(app, @Servo2KnobValueChanged, true);
            app.Servo2Knob.Tooltip = {'Shoulder'};
            app.Servo3KnobLabel = uilabel(app.LeftPanel);
            app.Servo3KnobLabel.Text = 'Servo #3';
            app.Servo3Knob = uiknob(app.LeftPanel, 'continuous');
            app.Servo3Knob.Limits = [0 180];
            app.Servo3Knob.MajorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180];
            app.Servo3Knob.ValueChangedFcn = createCallbackFcn(app, @Servo3KnobValueChanged, true);
            app.Servo3Knob.Tooltip = {'Elbow'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'XYZ Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StatusTextAreaLabel = uilabel(app.CenterPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.CenterPanel);
            app.MoveButton = uibutton(app.CenterPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.XEditFieldLabel = uilabel(app.CenterPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CenterPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CenterPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CenterPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CenterPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.Servo4KnobLabel = uilabel(app.RightPanel);
            app.Servo4KnobLabel.Text = 'Servo #4';
            app.Servo4Knob = uiknob(app.RightPanel, 'continuous');
            app.Servo4Knob.Limits = [0 180];
            app.Servo4Knob.MajorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180];
            app.Servo4Knob.ValueChangedFcn = createCallbackFcn(app, @Servo4KnobValueChanged, true);
            app.Servo4Knob.Tooltip = {'Wrist Angle'};
            app.Servo5KnobLabel = uilabel(app.RightPanel);
            app.Servo5KnobLabel.Text = 'Servo #5';
            app.Servo5Knob = uiknob(app.RightPanel, 'continuous');
            app.Servo5Knob.Limits = [0 360];
            app.Servo5Knob.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.Servo5Knob.ValueChangedFcn = createCallbackFcn(app, @Servo5KnobValueChanged, true);
            app.Servo5Knob.Tooltip = {'Wrist Rotate'};
            app.Servo6KnobLabel = uilabel(app.RightPanel);
            app.Servo6KnobLabel.Text = 'Servo #6';
            app.Servo6Knob = uiknob(app.RightPanel, 'continuous');
            app.Servo6Knob.Limits = [0 180];
            app.Servo6Knob.MajorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180];
            app.Servo6Knob.ValueChangedFcn = createCallbackFcn(app, @Servo6KnobValueChanged, true);
            app.Servo6Knob.Tooltip = {'Gripper'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RX150

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end